classdef PageGame < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure  matlab.ui.Figure
        Label     matlab.ui.control.Label
        L         matlab.ui.control.Button
        M         matlab.ui.control.Button
        N         matlab.ui.control.Button
        B         matlab.ui.control.Button
        V         matlab.ui.control.Button
        C         matlab.ui.control.Button
        X         matlab.ui.control.Button
        Y         matlab.ui.control.Button
        K         matlab.ui.control.Button
        J         matlab.ui.control.Button
        H         matlab.ui.control.Button
        G         matlab.ui.control.Button
        F         matlab.ui.control.Button
        D         matlab.ui.control.Button
        S         matlab.ui.control.Button
        A         matlab.ui.control.Button
        P         matlab.ui.control.Button
        O         matlab.ui.control.Button
        I         matlab.ui.control.Button
        U         matlab.ui.control.Button
        Z         matlab.ui.control.Button
        T         matlab.ui.control.Button
        R         matlab.ui.control.Button
        E         matlab.ui.control.Button
        W         matlab.ui.control.Button
        Q         matlab.ui.control.Button
        Image     matlab.ui.control.Image
    end

    properties (Access = private)
         displayText='';
         randomValue;
         k=0;
         counter=1;
         components; 
         result;
         loadedVector;
         guess;
         t;
         checker;
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % Taking the value from the workspace and storing in variable
            app.loadedVector = evalin('base', 'vector');
            app.checker=evalin('base','checker');
            app.result=evalin('base','result');
            
            % Takes the size of vector where words are stored 
            [rowCount, colCount] = size(app.loadedVector);
            
            % Checking if player has won
            app.guess=evalin('base','guess');
            app.t=colCount-floor(colCount/15);
            if app.guess>=app.t
                closereq;
                Winner;
                return;
            end
          
            % Generate random row and column indices
            randomRow = randi(rowCount);
            randomCol = randi(colCount);

            % Access the random value
            app.randomValue = app.loadedVector(randomRow, randomCol);

            % Checks if the word is empty or if it is repeated
            while strcmp(app.randomValue{1}, '') || any(ismember(app.checker, randomCol))

                % Generate random row and column indices
                randomRow = randi(rowCount);
                randomCol = randi(colCount);

                % Access the random value
                app.randomValue = app.loadedVector(randomRow, randomCol);
            end
            
            % Updating the variable checker
            app.checker=[app.checker randomCol];
            assignin('base','checker',app.checker);
            
            % Generate an underscore for each letter
            for wordIdx = 1:numel(app.randomValue)
               word = app.randomValue{wordIdx};
               for letterIdx = 1:length(word)
                   app.displayText = [app.displayText '_ ']; 
               end
            end

    	    % Set the label's Text property to the created string
            app.Label.Text = app.displayText; 
            
        end

        % Button pushed function: Q
        function QButtonPushed(app, event)
            if contains(app.randomValue, 'Q') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'Q'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='Q';  
                end
                delete(app.Q); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.Q); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: A
        function AButtonPushed(app, event)
            if contains(app.randomValue, 'A') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'A'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='A';  
                end
                delete(app.A); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.A); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: W
        function WButtonPushed(app, event)
            if contains(app.randomValue, 'W') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'W'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='W';  
                end
                delete(app.W); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.W); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: E
        function EButtonPushed(app, event)
            if contains(app.randomValue, 'E') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'E'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='E';  
                end
                delete(app.E); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.E); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: R
        function RButtonPushed(app, event)
            if contains(app.randomValue, 'R') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'R'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='R';  
                end
                delete(app.R); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.R); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: T
        function TButtonPushed(app, event)
            if contains(app.randomValue, 'T') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'T'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='T';  
                end
                delete(app.T); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.T); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: Z
        function ZButtonPushed(app, event)
            if contains(app.randomValue, 'Z') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'Z'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='Z';  
                end
                delete(app.Z); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.Z); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: U
        function UButtonPushed(app, event)
            if contains(app.randomValue, 'U') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'U'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='U';  
                end
                delete(app.U); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.U); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: I
        function IButtonPushed(app, event)
            if contains(app.randomValue, 'I') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'I'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='I';  
                end
                delete(app.I); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.I); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: O
        function OButtonPushed(app, event)
            if contains(app.randomValue, 'O') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'O'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='O';  
                end
                delete(app.O); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.O); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: P
        function PButtonPushed(app, event)
            if contains(app.randomValue, 'P') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'P'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='P';  
                end
                delete(app.P); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.P); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: S
        function SButtonPushed(app, event)
            if contains(app.randomValue, 'S') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'S'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='S';  
                end
                delete(app.S); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.S); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: D
        function DButtonPushed(app, event)
            if contains(app.randomValue, 'D') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'D'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='D';  
                end
                delete(app.D); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.D); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: F
        function FButtonPushed(app, event)
            if contains(app.randomValue, 'F') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'F'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='F';  
                end
                delete(app.F); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.F); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: G
        function GButtonPushed(app, event)
            if contains(app.randomValue, 'G') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'G'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='G';  
                end
                delete(app.G); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.G); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: H
        function HButtonPushed(app, event)
            if contains(app.randomValue, 'H') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'H'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='H';  
                end
                delete(app.H); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.H); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: J
        function JButtonPushed(app, event)
            if contains(app.randomValue, 'J') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'J'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='J';  
                end
                delete(app.J); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.J); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: K
        function KButtonPushed(app, event)
            if contains(app.randomValue, 'K') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'K'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='K';  
                end
                delete(app.K); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.K); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: Y
        function YButtonPushed(app, event)
            if contains(app.randomValue, 'Y') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'Y'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='Y';  
                end
                delete(app.Y); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.Y); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: X
        function XButtonPushed(app, event)
            if contains(app.randomValue, 'X') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'X'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='X';  
                end
                delete(app.X); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.X); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: C
        function CButtonPushed(app, event)
            if contains(app.randomValue, 'C') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'C'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='C';  
                end
                delete(app.C); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.C); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: V
        function VButtonPushed(app, event)
            if contains(app.randomValue, 'V') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'V'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='V';  
                end
                delete(app.V); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.V); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: B
        function BButtonPushed(app, event)
            if contains(app.randomValue, 'B') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'B'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='B';  
                end
                delete(app.B); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.B); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: N
        function NButtonPushed(app, event)
            if contains(app.randomValue, 'N') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'N'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='N';  
                end
                delete(app.N); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.N); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: M
        function MButtonPushed(app, event)
            if contains(app.randomValue, 'M') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'M'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='M';  
                end
                delete(app.M); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.M); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end

        % Button pushed function: L
        function LButtonPushed(app, event)
            if contains(app.randomValue, 'L') % Checking if the letter exists in the word
                % If the letter exists in the word
                positions=strfind(app.randomValue,'L'); % Finding the positions of letter in the word

                % Changing letter with underscore
                for index=1:numel(positions)
                    app.k=positions{index};
                    r=app.k;
                    app.displayText(r+(r-1))='L';  
                end
                delete(app.L); % Deleting letter's button
                app.Label.Text = app.displayText; % Updating the label with correct letter

                % Check if the player is guessed the word
                if ~contains(app.displayText,'_')
                    app.guess=app.guess+1; % Updating the variable
                    assignin('base','guess',app.guess); % Storing variable in workspace
                    app.result=app.result+11-app.counter; % Updating the score
                    assignin('base','result',app.result); % Storing score in workspace
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    PageGame;
                    app.delete;
                end
            else
                % If the letter does not exist in the word
                delete(app.L); % Deleting letter's button
                app.counter=app.counter+1; % Updating the variable
                app.Image.ImageSource=sprintf('game/HANGMAN-%d.png', app.counter); % Changing the image
                if app.counter==7 % Checking if the player has lost
                    pause(1);
                    assignin('base','result',app.result); % Storing the score of the player
                    app.Label.Text = app.randomValue; % Showing the correct answer
                    app.Image.ImageSource=sprintf('letter/%s.png', app.randomValue{1}); % Changing the image
                    pause(5);
                    FINAL;
                    app.delete;
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.Image = uiimage(app.UIFigure);
            app.Image.ImageSource = fullfile(pathToMLAPP, 'game', 'HANGMAN-1.png');
            app.Q = uibutton(app.UIFigure, 'push');
            app.Q.ButtonPushedFcn = createCallbackFcn(app, @QButtonPushed, true);
            app.Q.Text = 'Q';
            app.W = uibutton(app.UIFigure, 'push');
            app.W.ButtonPushedFcn = createCallbackFcn(app, @WButtonPushed, true);
            app.W.Text = 'W';
            app.E = uibutton(app.UIFigure, 'push');
            app.E.ButtonPushedFcn = createCallbackFcn(app, @EButtonPushed, true);
            app.E.Text = 'E';
            app.R = uibutton(app.UIFigure, 'push');
            app.R.ButtonPushedFcn = createCallbackFcn(app, @RButtonPushed, true);
            app.R.Text = 'R';
            app.T = uibutton(app.UIFigure, 'push');
            app.T.ButtonPushedFcn = createCallbackFcn(app, @TButtonPushed, true);
            app.T.Text = 'T';
            app.Z = uibutton(app.UIFigure, 'push');
            app.Z.ButtonPushedFcn = createCallbackFcn(app, @ZButtonPushed, true);
            app.Z.Text = 'Z';
            app.U = uibutton(app.UIFigure, 'push');
            app.U.ButtonPushedFcn = createCallbackFcn(app, @UButtonPushed, true);
            app.U.Text = 'U';
            app.I = uibutton(app.UIFigure, 'push');
            app.I.ButtonPushedFcn = createCallbackFcn(app, @IButtonPushed, true);
            app.I.Text = 'I';
            app.O = uibutton(app.UIFigure, 'push');
            app.O.ButtonPushedFcn = createCallbackFcn(app, @OButtonPushed, true);
            app.O.Text = 'O';
            app.P = uibutton(app.UIFigure, 'push');
            app.P.ButtonPushedFcn = createCallbackFcn(app, @PButtonPushed, true);
            app.P.Text = 'P';
            app.A = uibutton(app.UIFigure, 'push');
            app.A.ButtonPushedFcn = createCallbackFcn(app, @AButtonPushed, true);
            app.A.Text = 'A';
            app.S = uibutton(app.UIFigure, 'push');
            app.S.ButtonPushedFcn = createCallbackFcn(app, @SButtonPushed, true);
            app.S.Text = 'S';
            app.D = uibutton(app.UIFigure, 'push');
            app.D.ButtonPushedFcn = createCallbackFcn(app, @DButtonPushed, true);
            app.D.Text = 'D';
            app.F = uibutton(app.UIFigure, 'push');
            app.F.ButtonPushedFcn = createCallbackFcn(app, @FButtonPushed, true);
            app.F.Text = 'F';
            app.G = uibutton(app.UIFigure, 'push');
            app.G.ButtonPushedFcn = createCallbackFcn(app, @GButtonPushed, true);
            app.G.Text = 'G';
            app.H = uibutton(app.UIFigure, 'push');
            app.H.ButtonPushedFcn = createCallbackFcn(app, @HButtonPushed, true);
            app.H.Text = 'H';
            app.J = uibutton(app.UIFigure, 'push');
            app.J.ButtonPushedFcn = createCallbackFcn(app, @JButtonPushed, true);
            app.J.Text = 'J';
            app.K = uibutton(app.UIFigure, 'push');
            app.K.ButtonPushedFcn = createCallbackFcn(app, @KButtonPushed, true);
            app.K.Text = 'K';
            app.Y = uibutton(app.UIFigure, 'push');
            app.Y.ButtonPushedFcn = createCallbackFcn(app, @YButtonPushed, true);
            app.Y.Text = 'Y';
            app.X = uibutton(app.UIFigure, 'push');
            app.X.ButtonPushedFcn = createCallbackFcn(app, @XButtonPushed, true);
            app.X.Text = 'X';
            app.C = uibutton(app.UIFigure, 'push');
            app.C.ButtonPushedFcn = createCallbackFcn(app, @CButtonPushed, true);
            app.C.Text = 'C';
            app.V = uibutton(app.UIFigure, 'push');
            app.V.ButtonPushedFcn = createCallbackFcn(app, @VButtonPushed, true);
            app.V.Text = 'V';
            app.B = uibutton(app.UIFigure, 'push');
            app.B.ButtonPushedFcn = createCallbackFcn(app, @BButtonPushed, true);
            app.B.Text = 'B';
            app.N = uibutton(app.UIFigure, 'push');
            app.N.ButtonPushedFcn = createCallbackFcn(app, @NButtonPushed, true);
            app.N.Text = 'N';
            app.M = uibutton(app.UIFigure, 'push');
            app.M.ButtonPushedFcn = createCallbackFcn(app, @MButtonPushed, true);
            app.M.Text = 'M';
            app.L = uibutton(app.UIFigure, 'push');
            app.L.ButtonPushedFcn = createCallbackFcn(app, @LButtonPushed, true);
            app.L.Text = 'L';
            app.Label = uilabel(app.UIFigure);
            app.Label.Text = '';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = PageGame

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end