classdef SecondPage < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        CONFIRMButton            matlab.ui.control.Button
        CHOOSEYOURCATEGORYLabel  matlab.ui.control.Label
        ImageTOOL                matlab.ui.control.Image
        CheckTOOL                matlab.ui.control.CheckBox
        ImageFOOD                matlab.ui.control.Image
        CheckFOOD                matlab.ui.control.CheckBox
        ImageCOLORS              matlab.ui.control.Image
        CheckCOLORS              matlab.ui.control.CheckBox
        ImagePROFESSION          matlab.ui.control.Image
        CheckPROFESSION          matlab.ui.control.CheckBox
        ImageFRUITS              matlab.ui.control.Image
        CheckFRUITS              matlab.ui.control.CheckBox
        ImageSPORTS              matlab.ui.control.Image
        CheckSPORTS              matlab.ui.control.CheckBox
        ImageCOUNTRIES           matlab.ui.control.Image
        CheckCOUNTRIES           matlab.ui.control.CheckBox
        ImageINSTRUMENTAL        matlab.ui.control.Image
        CheckINSTRUMENTAL        matlab.ui.control.CheckBox
        ImageANIMAL              matlab.ui.control.Image
        CheckANIMAL              matlab.ui.control.CheckBox
        ImageLANG                matlab.ui.control.Image
        CheckLANG                matlab.ui.control.CheckBox
    end

    
    properties (Access = private) 
        myVector =[];
        vec=[];
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CONFIRMButton
        function CONFIRMButtonPushed(app, event)
            %Inserting words
            run("ListOfWords.m");
            if app.CheckANIMAL.Value==1
                app.vec=animals;
                app.vec = strsplit(app.vec, ' '); 
                app.myVector=[app.myVector app.vec];
            end
            if app.CheckCOUNTRIES.Value==1
                app.vec=countries;
                app.vec = strsplit(app.vec, ' '); 
                app.myVector=[app.myVector app.vec];
            end
            if app.CheckFRUITS.Value==1
                app.vec=fruits;
                app.vec = strsplit(app.vec, ' '); 
                app.myVector=[app.myVector app.vec];
            end
            if app.CheckPROFESSION.Value==1
                app.vec=professions;
                app.vec = strsplit(app.vec, ' '); 
                app.myVector=[app.myVector app.vec];
            end
            if app.CheckCOLORS.Value==1
                app.vec=colors;
                app.vec = strsplit(app.vec, ' '); 
                app.myVector=[app.myVector app.vec];
            end
            if app.CheckFOOD.Value==1
                app.vec=food;
                app.vec = strsplit(app.vec, ' '); 
                app.myVector=[app.myVector app.vec];
            end
            if app.CheckTOOL.Value==1
                app.vec=tools;
                app.vec = strsplit(app.vec, ' '); 
                app.myVector=[app.myVector app.vec];
            end
            if app.CheckLANG.Value==1
                app.vec=progLang;
                app.vec = strsplit(app.vec, ' '); 
                app.myVector=[app.myVector app.vec];
            end
            if app.CheckINSTRUMENTAL.Value==1
                app.vec=instruments;
                app.vec = strsplit(app.vec, ' '); 
                app.myVector=[app.myVector app.vec];
            end
            if app.CheckSPORTS.Value==1
                app.vec=sports;
                app.vec = strsplit(app.vec, ' '); 
                app.myVector=[app.myVector app.vec];
            end
            
            %Storing values in Workspace
            assignin('base','vector',app.myVector);
            assignin('base','result',0);
            assignin('base','guess',0);
            assignin('base','checker',[]);
            PageGame;
            app.delete;
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.CheckLANG = uicheckbox(app.UIFigure);
            app.CheckLANG.Text = {'PROGRAMING'; 'LANGUAGE'};
            app.ImageLANG = uiimage(app.UIFigure);
            app.ImageLANG.ImageSource = fullfile(pathToMLAPP, 'ICONS', 'coding-language.png');
            app.CheckANIMAL = uicheckbox(app.UIFigure);
            app.CheckANIMAL.Text = 'ANIMALS';
            app.ImageANIMAL = uiimage(app.UIFigure);
            app.ImageANIMAL.ImageSource = fullfile(pathToMLAPP, 'ICONS', 'livestock.png');
            app.CheckINSTRUMENTAL = uicheckbox(app.UIFigure);
            app.CheckINSTRUMENTAL.Text = {'MUSICAL'; 'INTRUMENTS'};
            app.ImageINSTRUMENTAL = uiimage(app.UIFigure);
            app.ImageINSTRUMENTAL.ImageSource = fullfile(pathToMLAPP, 'ICONS', 'music-instrument.png');
            app.CheckCOUNTRIES = uicheckbox(app.UIFigure);
            app.CheckCOUNTRIES.Text = 'COUNTRIES';
            app.ImageCOUNTRIES = uiimage(app.UIFigure);
            app.ImageCOUNTRIES.ImageSource = fullfile(pathToMLAPP, 'ICONS', 'coronavirus.png');
            app.CheckSPORTS = uicheckbox(app.UIFigure);
            app.CheckSPORTS.Text = 'SPORTS';
            app.ImageSPORTS = uiimage(app.UIFigure);
            app.ImageSPORTS.ImageSource = fullfile(pathToMLAPP, 'ICONS', 'balls-sports.png');
            app.CheckFRUITS = uicheckbox(app.UIFigure);
            app.CheckFRUITS.Text = 'FRUITS';
            app.ImageFRUITS = uiimage(app.UIFigure);
            app.ImageFRUITS.ImageSource = fullfile(pathToMLAPP, 'ICONS', 'fruits.png');
            app.CheckPROFESSION = uicheckbox(app.UIFigure);
            app.CheckPROFESSION.Text = 'PROFESSION';
            app.ImagePROFESSION = uiimage(app.UIFigure);
            app.ImagePROFESSION.ImageSource = fullfile(pathToMLAPP, 'ICONS', 'worker.png');
            app.CheckCOLORS = uicheckbox(app.UIFigure);
            app.CheckCOLORS.Text = 'COLORS';
            app.ImageCOLORS = uiimage(app.UIFigure);
            app.ImageCOLORS.ImageSource = fullfile(pathToMLAPP, 'ICONS', 'color-wheel.png');
            app.CheckFOOD = uicheckbox(app.UIFigure);
            app.CheckFOOD.Text = 'FOOD';
            app.ImageFOOD = uiimage(app.UIFigure);
            app.ImageFOOD.ImageSource = fullfile(pathToMLAPP, 'ICONS', 'fast-food.png');
            app.CheckTOOL = uicheckbox(app.UIFigure);
            app.CheckTOOL.Text = 'TOOLS';
            app.ImageTOOL = uiimage(app.UIFigure);
            app.ImageTOOL.ImageSource = fullfile(pathToMLAPP, 'ICONS', 'tool-box.png');
            app.CHOOSEYOURCATEGORYLabel = uilabel(app.UIFigure);
            app.CHOOSEYOURCATEGORYLabel.Text = 'CHOOSE YOUR CATEGORY';
            app.CONFIRMButton = uibutton(app.UIFigure, 'push');
            app.CONFIRMButton.ButtonPushedFcn = createCallbackFcn(app, @CONFIRMButtonPushed, true);
            app.CONFIRMButton.Text = 'CONFIRM';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = SecondPage

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end